classdef GradDesc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GradientOptimizationUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        FunctionEditFieldLabel        matlab.ui.control.Label
        FunctionEditField             matlab.ui.control.EditField
        Switch                        matlab.ui.control.ToggleSwitch
        StepSizeKnobLabel             matlab.ui.control.Label
        StepSizeKnob                  matlab.ui.control.DiscreteKnob
        CustomStepSize                matlab.ui.control.NumericEditField
        OPTIMIZEButton                matlab.ui.control.Button
        StartingValueEditFieldLabel   matlab.ui.control.Label
        StartingValueEditField        matlab.ui.control.EditField
        OPTIMIZEYOURFAVORITEFUNCTIONLabel  matlab.ui.control.Label
        MomentumSliderLabel           matlab.ui.control.Label
        MomentumSlider                matlab.ui.control.Slider
        ToleranceSpinnerLabel         matlab.ui.control.Label
        ToleranceSpinner              matlab.ui.control.Spinner
        RightPanel                    matlab.ui.container.Panel
        FunctionAxes                  matlab.ui.control.UIAxes
        EfficiencyAxes                matlab.ui.control.UIAxes
        OPTIMALVALUESLabel            matlab.ui.control.Label
        OutputField                   matlab.ui.control.TextArea
        StatusLamp                    matlab.ui.control.Lamp
        ViewTypeDropDown              matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OPTIMIZEButton
        function OPTIMIZEButtonPushed(app, event)
            app.StatusLamp.Color = 'r';
            drawnow();
            
            fun = str2symfun(app.FunctionEditField.Value);
            stepSize = str2double(app.StepSizeKnob.Value);
            if(stepSize == 1)
                stepSize = app.CustomStepSize.Value;
            end
            momentum = app.MomentumSlider.Value;
            startingValues = eval("[" + app.StartingValueEditField.Value + "]")';
            optimizationType = app.Switch.Value;
            tolerance = app.ToleranceSpinner.Value;
            
            [optimumValue, trail] = GradOpt(fun,startingValues,stepSize,momentum,tolerance,optimizationType);
            
            outText = "";
            vars = getVars(app.FunctionEditField.Value);
            
            for i = 1:length(vars)
                outText = outText + vars{i} + ": " + double(optimumValue(i)) + "\n";
            end
            app.OutputField.Value = compose(outText);
            
            cla(app.FunctionAxes,'reset')
            cla(app.EfficiencyAxes,'reset')
            
            if (length(vars) == 1)
                X = trail;
                Y = fun(trail);
                
                hold(app.FunctionAxes, "on");
                fplot(app.FunctionAxes,fun);
                plot(app.FunctionAxes,X,Y,'r-')
                plot(app.FunctionAxes,optimumValue,fun(optimumValue),'ro')
                hold(app.FunctionAxes, "off");
            elseif (length(vars) == 2)
                X = trail(1,:);
                Y = trail(2,:);
                Z = fun(trail(1,:),trail(2,:));
                
                hold(app.FunctionAxes, "on");
                fsurf(app.FunctionAxes,fun,'FaceAlpha',0.2);
                plot3(app.FunctionAxes,X,Y,Z,"r-+")
                plot3(app.FunctionAxes,optimumValue(1),optimumValue(2),fun(optimumValue(1),optimumValue(2)),"ro")
                app.FunctionAxes.XLim = [0 1];
                
                view(app.FunctionAxes,45, 45);
                
                hold(app.FunctionAxes, "off");
            else
                text(app.FunctionAxes,0,0,"Cannot visualize function")
                xlim(app.FunctionAxes, [-1 1])
                ylim(app.FunctionAxes, [-1 1])
            end
            
            dist = 0;
            for n = 1:size(trail,2)
                dist(n)=sqrt(sum((optimumValue-trail(:,n)).^2));
            end
            plot(app.EfficiencyAxes, dist);
            
            app.StatusLamp.Color = 'g';
        end

        % Value changed function: StepSizeKnob
        function StepSizeKnobValueChanged(app, event)
            if(str2double(app.StepSizeKnob.Value) == 1)
                app.CustomStepSize.Enable = true;
            else
                app.CustomStepSize.Enable = false;
                app.CustomStepSize.Value = str2double(app.StepSizeKnob.Value);
            end
        end

        % Value changed function: ViewTypeDropDown
        function ViewTypeDropDownValueChanged(app, event)
            set(app.FunctionAxes,'Projection',app.ViewTypeDropDown.Value)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GradientOptimizationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {590, 590};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {364, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GradientOptimizationUIFigure = uifigure('Visible', 'off');
            app.GradientOptimizationUIFigure.Name = 'Gradient Optimization';
            app.GradientOptimizationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GradientOptimizationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionEditFieldLabel = uilabel(app.LeftPanel);
            app.FunctionEditFieldLabel.Text = 'Function:';
            app.FunctionEditField = uieditfield(app.LeftPanel, 'text');
            app.FunctionEditField.Tooltip = {'i.e. f(x,y)=x^2+y^2'};
            app.Switch = uiswitch(app.LeftPanel, 'toggle');
            app.Switch.Items = {'Descent', 'Ascent'};
            app.Switch.Value = 'Descent';
            app.StepSizeKnobLabel = uilabel(app.LeftPanel);
            app.StepSizeKnobLabel.Text = 'Step Size';
            app.StepSizeKnob = uiknob(app.LeftPanel, 'discrete');
            app.StepSizeKnob.Items = {'Baby Steps', 'Regular Steps', 'SUPER STEPS', 'Custom'};
            app.StepSizeKnob.ItemsData = {'0.01', '0.1', '0.5', '1'};
            app.StepSizeKnob.ValueChangedFcn = createCallbackFcn(app, @StepSizeKnobValueChanged, true);
            app.StepSizeKnob.Value = '0.1';
            app.CustomStepSize = uieditfield(app.LeftPanel, 'numeric');
            app.CustomStepSize.Enable = 'off';
            app.CustomStepSize.Value = 0.1;
            app.OPTIMIZEButton = uibutton(app.LeftPanel, 'push');
            app.OPTIMIZEButton.ButtonPushedFcn = createCallbackFcn(app, @OPTIMIZEButtonPushed, true);
            app.OPTIMIZEButton.Text = 'OPTIMIZE';
            app.StartingValueEditFieldLabel = uilabel(app.LeftPanel);
            app.StartingValueEditFieldLabel.Text = 'Starting Value:';
            app.StartingValueEditField = uieditfield(app.LeftPanel, 'text');
            app.OPTIMIZEYOURFAVORITEFUNCTIONLabel = uilabel(app.LeftPanel);
            app.OPTIMIZEYOURFAVORITEFUNCTIONLabel.Text = 'OPTIMIZE YOUR FAVORITE FUNCTION';
            app.MomentumSliderLabel = uilabel(app.LeftPanel);
            app.MomentumSliderLabel.Text = 'Momentum';
            app.MomentumSlider = uislider(app.LeftPanel);
            app.MomentumSlider.Limits = [0 1];
            app.ToleranceSpinnerLabel = uilabel(app.LeftPanel);
            app.ToleranceSpinnerLabel.Text = 'Tolerance';
            app.ToleranceSpinner = uispinner(app.LeftPanel);
            app.ToleranceSpinner.Limits = [1 Inf];
            app.ToleranceSpinner.Tooltip = {'Tolerance is in decible places,ie, actual tolerance = 10^-(toleranceValue)'};
            app.ToleranceSpinner.Value = 3;
            app.RightPanel = uipanel(app.GridLayout);
            app.FunctionAxes = uiaxes(app.RightPanel);
            title(app.FunctionAxes, 'FUNCTION')
            xlabel(app.FunctionAxes, 'X')
            ylabel(app.FunctionAxes, 'Y')
            app.EfficiencyAxes = uiaxes(app.RightPanel);
            title(app.EfficiencyAxes, 'Step Size Efficiency')
            xlabel(app.EfficiencyAxes, 'Step#')
            ylabel(app.EfficiencyAxes, 'Distance To Point')
            app.OPTIMALVALUESLabel = uilabel(app.RightPanel);
            app.OPTIMALVALUESLabel.Text = 'OPTIMAL VALUES:';
            app.OutputField = uitextarea(app.RightPanel);
            app.OutputField.Editable = 'off';
            app.StatusLamp = uilamp(app.RightPanel);
            app.ViewTypeDropDown = uidropdown(app.RightPanel);
            app.ViewTypeDropDown.Items = {'Orthographic', 'Perspective'};
            app.ViewTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewTypeDropDownValueChanged, true);
            app.ViewTypeDropDown.Value = 'Orthographic';
            % Show the figure after all components are created
            app.GradientOptimizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GradDesc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GradientOptimizationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GradientOptimizationUIFigure)
        end
    end
end